classdef Image_Processing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        applyButton_2               matlab.ui.control.Button
        MergeImagesDropDown         matlab.ui.control.DropDown
        MergeImagesDropDownLabel    matlab.ui.control.Label
        applyButton_3               matlab.ui.control.Button
        NoiseFiltersDropDown        matlab.ui.control.DropDown
        NoiseFiltersDropDownLabel   matlab.ui.control.Label
        applyButton                 matlab.ui.control.Button
        OperationsDropDown          matlab.ui.control.DropDown
        OperationsDropDownLabel     matlab.ui.control.Label
        LoadImageButton             matlab.ui.control.Button
        CenterPanel                 matlab.ui.container.Panel
        ContrastSlider              matlab.ui.control.Slider
        ContrastSliderLabel         matlab.ui.control.Label
        BrightnessSlider            matlab.ui.control.Slider
        BrightnessSliderLabel       matlab.ui.control.Label
        WorkSpaceLabel              matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        RightPanel                  matlab.ui.container.Panel
        applyButton_5               matlab.ui.control.Button
        NoisesDropDown              matlab.ui.control.DropDown
        NoisesDropDownLabel         matlab.ui.control.Label
        applyButton_4               matlab.ui.control.Button
        EdgeDetectionDropDown       matlab.ui.control.DropDown
        EdgeDetectionDropDownLabel  matlab.ui.control.Label
        ResetImageButton            matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        Img % Description
        updatedImg % Description
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes,'off')
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
           [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           if isequal(filename,0)
               figure(app.UIFigure);
               return;
           end
           figure(app.UIFigure);
           app.Img = imread(fullfile(filepath, filename));
           app.Img = imresize(app.Img, [500, 500]);
           app.updatedImg = app.Img;
           imshow(app.Img,'parent',app.UIAxes)
            
        end

        % Callback function: not associated with a component
        function BrightnessSliderValueChanging(app, event)


        end

        % Button pushed function: applyButton
        function applyButtonPushed(app, event)
            switch app.OperationsDropDown.Value
                case 'Inverse Transform'
                    app.updatedImg=im2gray(app.updatedImg);
                    app.updatedImg = imcomplement(app.updatedImg);
                    figure(app.UIFigure);
                    imshow(app.updatedImg,'parent',app.UIAxes);
                case 'Logaritmic Transform'
                   
                    L=255;
                    c=L/log10(1+L);
                    app.updatedImg=c*log10(1+double(app.updatedImg));
                    figure(app.UIFigure);
                    imshow(uint8(app.updatedImg),'parent',app.UIAxes);
                    uialert(app.UIFigure,'Please reset image after Logarithmic and Power Law Transform before doing any furthur modifications','Warning');
                case 'Power Law Transform'
                    I = rgb2gray(app.updatedImg);
                    gamma=1.1;
                    I=double(I).^gamma;
                    app.updatedImg = I;
                    figure(app.UIFigure);
                    imshow(uint8(app.updatedImg),'parent',app.UIAxes);
                    uialert(app.UIFigure,'Please reset image after Logarithmic and Power Law Transform before doing any furthur modifications','Warning');
                case 'Histogram equilization'
                    app.updatedImg = histeq(app.updatedImg);
                    figure(app.UIFigure);
                    imshow(app.updatedImg,'parent',app.UIAxes);
                case 'Dilation'
                    b = strel('ball',5,5);
                    app.updatedImg = imdilate(app.updatedImg,b);
                    figure(app.UIFigure);
                    imshow(app.updatedImg,'parent',app.UIAxes);
                case 'Erosion'
                    b = strel('ball',5,5);
                    app.updatedImg = imerode(app.updatedImg,b);
                    figure(app.UIFigure);
                    imshow(app.updatedImg,'parent',app.UIAxes);
                
            end
        end

        % Button pushed function: ResetImageButton
        function ResetImageButtonPushed(app, event)
            app.BrightnessSlider.Value = 0;
            app.updatedImg = app.Img;
            figure(app.UIFigure);
            imshow(app.updatedImg,'parent',app.UIAxes);
        end

        % Button pushed function: applyButton_3
        function applyButton_3Pushed(app, event)
            switch app.NoiseFiltersDropDown.Value
                case 'Mean Linear Filter'
                    app.updatedImg = im2gray(app.updatedImg);
                    app.updatedImg = conv2(single(app.updatedImg), ones(3)/9);
                    figure(app.UIFigure);
                    imshow(uint8(app.updatedImg),'parent',app.UIAxes);
                case 'Median Filter'
                    app.updatedImg = im2gray(app.updatedImg);
                    app.updatedImg=medfilt2(app.updatedImg,[5 5]);
                    figure(app.UIFigure);
                    imshow(app.updatedImg,'parent',app.UIAxes);
                case 'Inverse Filter blur removel'
                    freqx = fft2(x);
                    freqy = fft2(app.updatedImg);
                    powfreqx = freqx.^2/(M*N);
                    alpha = 0.5;%Indicates inverse filter
                    freqg = ((freqh.^1)).*abs(powfreqx) ...
                    ./(abs(freqh.^2).*abs(powfreqx)+ alpha*sigma^2);
                    Resfreqx = freqg.*freqy;
                    Resa = real(ifft2(Resfreqx));
                    figure(app.UIFigure);
                    imshow(uint8(app.updatedImg),'parent',app.UIAxes);
                case 'Wiener Filter'
                    app.updatedImg = im2gray(app.updatedImg);
                    app.updatedImg=wiener2(app.updatedImg, [3 3]);
                    figure(app.UIFigure);
                    imshow(app.updatedImg,'parent',app.UIAxes);

            end

        end

        % Button pushed function: applyButton_2
        function applyButton_2Pushed(app, event)
            [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select Another Image to Merge');
            
            img = imread(fullfile(filepath, filename));
            img = imresize(img, [500,500]);
            try
                switch app.MergeImagesDropDown.Value
                    case 'Addition'
                        app.updatedImg  = imadd(img, app.updatedImg);
                        figure(app.UIFigure);
                        imshow(app.updatedImg,'parent',app.UIAxes);
                    case 'Subtraction'
                        app.updatedImg  = imsubtract(img, app.updatedImg);
                        figure(app.UIFigure);
                        imshow(app.updatedImg,'parent',app.UIAxes);
                    case 'Multiplication'
                        app.updatedImg  = immultiply(img, app.updatedImg);
                        figure(app.UIFigure);
                        imshow(app.updatedImg,'parent',app.UIAxes);
                    case 'Division'
                        app.updatedImg  = imdivide(img, app.updatedImg);
                        figure(app.UIFigure);
                        imshow(app.updatedImg,'parent',app.UIAxes);
                end
            catch 
                uialert(app.UIFigure,'Image is not Compatible, Please choose another Image', 'Warning');

            end
       
        end

        % Button pushed function: applyButton_4
        function applyButton_4Pushed(app, event)
            img = im2gray(app.updatedImg)
            switch app.EdgeDetectionDropDown.Value
                case 'Sobel'
                    
                    A = edge(img,'sobel');
                    figure(app.UIFigure);
                    imshow(A,'parent',app.UIAxes);
                case 'Canny'

                    B = edge(img,'canny');
                    figure(app.UIFigure);
                    imshow(B,'parent',app.UIAxes);
                case 'Prewitt'

                    C = edge(img,'prewitt');
                    figure(app.UIFigure);
                    imshow(C,'parent',app.UIAxes);
                case 'Roberts'

                    D = edge(img,'roberts');
                    figure(app.UIFigure);
                    imshow(D,'parent',app.UIAxes);

            end
        end

        % Button pushed function: applyButton_5
        function applyButton_5Pushed(app, event)
            switch app.NoisesDropDown.Value
                case 'Salt'
                    app.updatedImg=imnoise(app.updatedImg,'salt & pepper', 0.2);
                    figure(app.UIFigure);
                    imshow(app.updatedImg,'parent',app.UIAxes);
                case 'Salt and Pepper'
                    app.updatedImg=imnoise(app.updatedImg,'salt ', 0.2);
                    figure(app.UIFigure);
                    imshow(app.updatedImg,'parent',app.UIAxes);
                case 'Gaussian'
                    app.updatedImg=imnoise(app.updatedImg,'gaussian', 0.12);
                    figure(app.UIFigure);
                    imshow(app.updatedImg,'parent',app.UIAxes);
                case 'Poission'
                    app.updatedImg=imnoise(app.updatedImg,'poisson');
                    figure(app.UIFigure);
                    imshow(app.updatedImg,'parent',app.UIAxes);
                case 'Speckle'
                    app.updatedImg=imnoise(app.updatedImg,'salt', 0.12);
                    figure(app.UIFigure);
                    imshow(app.updatedImg,'parent',app.UIAxes);
            end
        end

        % Callback function: not associated with a component
        function HueSliderValueChanging(app, event)
            changingValue = event.Value;
            hsvImage = rgb2hsv(app.updatedImg);
            [h, s, v] = imsplit(hsvImage);
            h = h + changingValue;
            hsvImage = cat(3, h, s, v);
            app.updatedImg = hsv2rgb(hsvImage);
            figure(app.UIFigure);
            imshow(app.updatedImg,'parent',app.UIAxes);
        end

        % Value changing function: BrightnessSlider
        function BrightnessSliderValueChanging2(app, event)
            changingValue = event.Value;
            app.updatedImg = app.updatedImg + changingValue;
            
            figure(app.UIFigure);
            imshow(app.updatedImg,'parent',app.UIAxes)
        end

        % Value changing function: ContrastSlider
        function ContrastSliderValueChanging(app, event)
            changingValue = event.Value;
            img = rgb2hsv(app.updatedImg);
            v = img(:,:,3);
            v1 = imadjust(v, [0.0 1], changingValue);
            img(:,:,3) = v1;
            app.updatedImg = hsv2rgb(img);
            figure(app.UIFigure);
            imshow(app.updatedImg,'parent',app.UIAxes)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {186, '1x', 186};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadImageButton = uibutton(app.LeftPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.OperationsDropDownLabel = uilabel(app.LeftPanel);
            app.OperationsDropDownLabel.Text = 'Operations';
            app.OperationsDropDown = uidropdown(app.LeftPanel);
            app.OperationsDropDown.Items = {'Inverse Transform', 'Logaritmic Transform', 'Power Law Transform', 'Histogram equilization', 'Dilation', 'Erosion'};
            app.OperationsDropDown.Value = 'Inverse Transform';
            app.applyButton = uibutton(app.LeftPanel, 'push');
            app.applyButton.ButtonPushedFcn = createCallbackFcn(app, @applyButtonPushed, true);
            app.applyButton.Text = 'apply';
            app.NoiseFiltersDropDownLabel = uilabel(app.LeftPanel);
            app.NoiseFiltersDropDownLabel.Text = 'Noise Filters';
            app.NoiseFiltersDropDown = uidropdown(app.LeftPanel);
            app.NoiseFiltersDropDown.Items = {'Mean Linear Filter', 'Median Filter', 'Inverse Filter blur removel', 'Wiener Filter'};
            app.NoiseFiltersDropDown.Value = 'Median Filter';
            app.applyButton_3 = uibutton(app.LeftPanel, 'push');
            app.applyButton_3.ButtonPushedFcn = createCallbackFcn(app, @applyButton_3Pushed, true);
            app.applyButton_3.Text = 'apply';
            app.MergeImagesDropDownLabel = uilabel(app.LeftPanel);
            app.MergeImagesDropDownLabel.Text = 'Merge Images';
            app.MergeImagesDropDown = uidropdown(app.LeftPanel);
            app.MergeImagesDropDown.Items = {'Addition', 'Subtraction', 'Multiplication', 'Division'};
            app.MergeImagesDropDown.Value = 'Addition';
            app.applyButton_2 = uibutton(app.LeftPanel, 'push');
            app.applyButton_2.ButtonPushedFcn = createCallbackFcn(app, @applyButton_2Pushed, true);
            app.applyButton_2.Text = 'apply';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.WorkSpaceLabel = uilabel(app.CenterPanel);
            app.WorkSpaceLabel.Text = 'WorkSpace';
            app.BrightnessSliderLabel = uilabel(app.CenterPanel);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.CenterPanel);
            app.BrightnessSlider.Limits = [-20 20];
            app.BrightnessSlider.MajorTicks = [];
            app.BrightnessSlider.MajorTickLabels = {''};
            app.BrightnessSlider.ValueChangingFcn = createCallbackFcn(app, @BrightnessSliderValueChanging2, true);
            app.BrightnessSlider.MinorTicks = [];
            app.ContrastSliderLabel = uilabel(app.CenterPanel);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.CenterPanel);
            app.ContrastSlider.Limits = [0.1 1];
            app.ContrastSlider.MajorTicks = [];
            app.ContrastSlider.MajorTickLabels = {''};
            app.ContrastSlider.ValueChangingFcn = createCallbackFcn(app, @ContrastSliderValueChanging, true);
            app.ContrastSlider.MinorTicks = [];
            app.ContrastSlider.Value = 0.1;
            app.RightPanel = uipanel(app.GridLayout);
            app.ResetImageButton = uibutton(app.RightPanel, 'push');
            app.ResetImageButton.ButtonPushedFcn = createCallbackFcn(app, @ResetImageButtonPushed, true);
            app.ResetImageButton.Text = 'Reset Image';
            app.EdgeDetectionDropDownLabel = uilabel(app.RightPanel);
            app.EdgeDetectionDropDownLabel.Text = 'Edge Detection';
            app.EdgeDetectionDropDown = uidropdown(app.RightPanel);
            app.EdgeDetectionDropDown.Items = {'Sobel', 'Canny', 'Prewitt', 'Roberts'};
            app.EdgeDetectionDropDown.Value = 'Sobel';
            app.applyButton_4 = uibutton(app.RightPanel, 'push');
            app.applyButton_4.ButtonPushedFcn = createCallbackFcn(app, @applyButton_4Pushed, true);
            app.applyButton_4.Text = 'apply';
            app.NoisesDropDownLabel = uilabel(app.RightPanel);
            app.NoisesDropDownLabel.Text = 'Noises';
            app.NoisesDropDown = uidropdown(app.RightPanel);
            app.NoisesDropDown.Items = {'Salt', 'Salt and Pepper', 'Gaussian', 'Poission', 'Speckle'};
            app.NoisesDropDown.Value = 'Salt';
            app.applyButton_5 = uibutton(app.RightPanel, 'push');
            app.applyButton_5.ButtonPushedFcn = createCallbackFcn(app, @applyButton_5Pushed, true);
            app.applyButton_5.Text = 'apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Processing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end